classdef appminims < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GradoEditField       matlab.ui.control.NumericEditField
        GradoEditFieldLabel  matlab.ui.control.Label
        IngresalosdatosseparadosporespaciosLabel  matlab.ui.control.Label
        txtResultados        matlab.ui.control.TextArea
        ResultadoLabel       matlab.ui.control.Label
        btnLimpiar           matlab.ui.control.Button
        btnCalcular          matlab.ui.control.Button
        txtY                 matlab.ui.control.TextArea
        YLabel               matlab.ui.control.Label
        txtX                 matlab.ui.control.TextArea
        XLabel               matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)
try


    X = str2num(strjoin(app.txtX.Value));
    Y = str2num(strjoin(app.txtY.Value));

 
    grado = app.GradoEditField.Value;

 
    if isempty(X) || isempty(Y)
        uialert(app.UIFigure,...
            'Ingrese los datos correctamente',...
            'Error');
        return
    end

    if length(X) ~= length(Y)
        uialert(app.UIFigure,...
            'X y Y deben tener la misma cantidad de datos',...
            'Error');
        return
    end

    if grado < 1
        uialert(app.UIFigure,...
            'Ingrese un grado válido',...
            'Error');
        return
    end

    if grado > length(X)-1
        uialert(app.UIFigure,...
            'El grado no puede ser mayor que cantidad de datos - 1',...
            'Error');
        return
    end

    
    coef = polyfit(X,Y,grado);


    Yajustada = polyval(coef,X);


    SSres = sum((Y - Yajustada).^2);
    SStot = sum((Y - mean(Y)).^2);
    R2 = 1 - (SSres / SStot);

    
    A1 = coef(1);
    A0 = coef(2);

   
    if A1 >= 0
        ecuacion = sprintf('Y = %.2f + %.2fX', A0, A1);
    else
        ecuacion = sprintf('Y = %.2f - %.2fX', A0, abs(A1));
    end

   
    resultado = sprintf([ ...
        'Ecuacion:\n%s\n\n' ...
        'Coeficiente de determinacion R²:\n%.4f'], ...
        ecuacion, R2);

    app.txtResultados.Value = resultado;

   
    xModelo = linspace(min(X), max(X), 100);
    yModelo = polyval(coef,xModelo);

    
    cla(app.UIAxes)


    scatter(app.UIAxes,X,Y,80,'filled')
    hold(app.UIAxes,'on')

   
    plot(app.UIAxes,xModelo,yModelo,'LineWidth',2)

    grid(app.UIAxes,'on')

    title(app.UIAxes,...
        ['Ajuste Polinomial Grado ', num2str(grado)])

    xlabel(app.UIAxes,'Variable X')
    ylabel(app.UIAxes,'Variable Y')

    legend(app.UIAxes,...
        'Datos experimentales',...
        'Modelo ajustado')

    hold(app.UIAxes,'off')

catch ME

    uialert(app.UIFigure,...
        ME.message,...
        'Error')

end

        end

        % Button pushed function: btnLimpiar
        function btnLimpiarButtonPushed(app, event)
app.txtX.Value = {''};
app.txtY.Value = {''};
app.txtResultados.Value = {''};

app.GradoEditField.Value = 1;

cla(app.UIAxes)
grid(app.UIAxes,'off')
title(app.UIAxes,'')
xlabel(app.UIAxes,'')
ylabel(app.UIAxes,'')
legend(app.UIAxes,'off')
        end

        % Value changed function: GradoEditField
        function GradoEditFieldValueChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'Vector X';
            app.txtX = uitextarea(app.UIFigure);
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Vector Y';
            app.txtY = uitextarea(app.UIFigure);
            app.btnCalcular = uibutton(app.UIFigure, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Text = 'Calcular';
            app.btnLimpiar = uibutton(app.UIFigure, 'push');
            app.btnLimpiar.ButtonPushedFcn = createCallbackFcn(app, @btnLimpiarButtonPushed, true);
            app.btnLimpiar.Text = 'Limpiar';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.txtResultados = uitextarea(app.UIFigure);
            app.IngresalosdatosseparadosporespaciosLabel = uilabel(app.UIFigure);
            app.IngresalosdatosseparadosporespaciosLabel.Text = 'Ingresa los datos separados por espacios';
            app.GradoEditFieldLabel = uilabel(app.UIFigure);
            app.GradoEditFieldLabel.Text = 'Grado';
            app.GradoEditField = uieditfield(app.UIFigure, 'numeric');
            app.GradoEditField.ValueChangedFcn = createCallbackFcn(app, @GradoEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appminims

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end