classdef apptaylor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalculadoraSeriedeTaylor  matlab.ui.Figure
        Ejemplodefuncionessinxexpxx32x1Label  matlab.ui.control.Label
        txtResultados             matlab.ui.control.TextArea
        ResultadoLabel            matlab.ui.control.Label
        btnLimpiar                matlab.ui.control.Button
        btnCalcular               matlab.ui.control.Button
        txtH                      matlab.ui.control.NumericEditField
        txhLabel                  matlab.ui.control.Label
        txtN                      matlab.ui.control.NumericEditField
        txnLabel                  matlab.ui.control.Label
        txtA                      matlab.ui.control.NumericEditField
        txaLabel                  matlab.ui.control.Label
        txtFuncion                matlab.ui.control.EditField
        FuncionLabel              matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnCalcular
        function btnCalcularButtonPushed(app, event)
            syms x

try

    fStr = app.txtFuncion.Value;
    if isempty(fStr)

    uialert(app.CalculadoraSeriedeTaylor, ...
        'Ingrese una funcion', ...
        'Error');

    return

    end
if isempty(fStr)

    uialert(app.CalculadoraSeriedeTaylor, ...
        'Ingrese una funcion', ...
        'Error');

    return

end

    f = str2sym(fStr);

    a = app.txtA.Value;
    n = app.txtN.Value;
    h = app.txtH.Value;
    app.UIAxes.XGrid = 'on';
    app.UIAxes.YGrid = 'on';

    if n < 0

        uialert(app.CalculadoraSeriedeTaylor, ...
            'El orden no puede ser negativo', ...
            'Error');

        return


    end

    xi_1 = a + h;

    T = taylor(f, x, ...
        'ExpansionPoint', a, ...
        'Order', n + 1);

    valorAproximado = ...
        double(subs(T, x, xi_1));

    valorReal = ...
        double(subs(f, x, xi_1));

    Et = abs(valorReal - valorAproximado);
if valorReal ~= 0
    Ep = abs((Et / valorReal) * 100);
else
    Ep = 0;
end

   resultado = sprintf([ ...
    'Funcion: %s\n\n' ...
    'Polinomio:\n%s\n\n' ...
    'Punto Evaluado: %.4f\n\n' ...
    'Valor Real: %.6f\n' ...
    'Valor Aproximado: %.6f\n' ...
    'Error Absoluto: %.6f\n' ...
    'Error Porcentual: %.4f%%'], ...
    fStr, ...
    char(T), ...
    xi_1, ...
    valorReal, ...
    valorAproximado, ...
    Et, ...
    Ep);

    app.txtResultados.Value = resultado;

    cla(app.UIAxes)

    dominio = [a - 5, a + 5];

    fplot(app.UIAxes, ...
        f, ...
        dominio, ...
        'b', ...
        'LineWidth', 2)

    hold(app.UIAxes, 'on')

    fplot(app.UIAxes, ...
        T, ...
        dominio, ...
        'r--', ...
        'LineWidth', 2)

    scatter(app.UIAxes, ...
        xi_1, ...
        valorReal, ...
        80, ...
        'filled')

    grid(app.UIAxes, 'on')

    title(app.UIAxes, ...
    sprintf('Taylor orden %d', n))

    legend(app.UIAxes, ...
        'Funcion Real', ...
        'Taylor', ...
        'Punto Evaluado')

catch ME

    uialert(app.CalculadoraSeriedeTaylor, ...
        ME.message, ...
        'Error')

end

        end

        % Button pushed function: btnLimpiar
        function btnLimpiarButtonPushed(app, event)
            app.txtFuncion.Value = '';

app.txtA.Value = 0;

app.txtN.Value = 0;

app.txtH.Value = 0;

app.txtResultados.Value = '';

cla(app.UIAxes)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalculadoraSeriedeTaylor = uifigure('Visible', 'off');
            app.CalculadoraSeriedeTaylor.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.CalculadoraSeriedeTaylor);
            title(app.UIAxes, 'Grafica de funcion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FuncionLabel = uilabel(app.CalculadoraSeriedeTaylor);
            app.FuncionLabel.Text = 'Funcion';
            app.txtFuncion = uieditfield(app.CalculadoraSeriedeTaylor, 'text');
            app.txaLabel = uilabel(app.CalculadoraSeriedeTaylor);
            app.txaLabel.Text = 'Punto de expansion';
            app.txtA = uieditfield(app.CalculadoraSeriedeTaylor, 'numeric');
            app.txnLabel = uilabel(app.CalculadoraSeriedeTaylor);
            app.txnLabel.Text = 'Orden n';
            app.txtN = uieditfield(app.CalculadoraSeriedeTaylor, 'numeric');
            app.txhLabel = uilabel(app.CalculadoraSeriedeTaylor);
            app.txhLabel.Text = 'Paso h';
            app.txtH = uieditfield(app.CalculadoraSeriedeTaylor, 'numeric');
            app.btnCalcular = uibutton(app.CalculadoraSeriedeTaylor, 'push');
            app.btnCalcular.ButtonPushedFcn = createCallbackFcn(app, @btnCalcularButtonPushed, true);
            app.btnCalcular.Text = 'Calcular';
            app.btnLimpiar = uibutton(app.CalculadoraSeriedeTaylor, 'push');
            app.btnLimpiar.ButtonPushedFcn = createCallbackFcn(app, @btnLimpiarButtonPushed, true);
            app.btnLimpiar.Text = 'Limpiar';
            app.ResultadoLabel = uilabel(app.CalculadoraSeriedeTaylor);
            app.ResultadoLabel.Text = 'Resultado';
            app.txtResultados = uitextarea(app.CalculadoraSeriedeTaylor);
            app.Ejemplodefuncionessinxexpxx32x1Label = uilabel(app.CalculadoraSeriedeTaylor);
            app.Ejemplodefuncionessinxexpxx32x1Label.Text = {'Ejemplo de funciones:     sin(x)'; 'exp(x)'; 'x^3-2*x+1 '};
            % Show the figure after all components are created
            app.CalculadoraSeriedeTaylor.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptaylor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalculadoraSeriedeTaylor)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalculadoraSeriedeTaylor)
        end
    end
end